classdef ImageComparisonTool < matlab.ui.componentcontainer.ComponentContainer
    %IMAGECOMPARISONTOOL An example UI component for comparing two images
    %with linked viewers
    %   The ImageComparisonTool component displays two images side by side
    %   with synchronized zoom and pan interactions.
    %   
    %   ict = IMAGECOMPARISONTOOL creates an ImageComparisonTool component
    %   in a new UI figure and returns the ImageComparisonTool object.
    %   MATLAB calls the UIFIGURE function to create the parent figure. The
    %   component displays images in two viewers, which are linked by
    %   default.
    %   
    %   ict = IMAGECOMPARISONTOOL(parent) creates an ImageComparisonTool
    %   component in the specified parent container. The parent can be a
    %   figure or any UI container.
    %   
    %   ict = IMAGECOMPARISONTOOL(___,Name=Value) creates an
    %   ImageComparisonTool component with properties configured using
    %   name-value arguments. For example, you can specify the Image1 and
    %   Image2 data. Use this syntax with any of the input argument
    %   combinations from the previous syntaxes.
    %   
    %   Example 1: Create the component
    %      ict = ImageComparisonTool;
    %   
    %   Example 2: Create the component in a UI figure
    %      fig = uifigure;
    %      ict = ImageComparisonTool(fig);
    %   
    %   Example 3: Create the component and set properties
    %      image1 = imread("cameraman.tif");
    %      image2 = imrotate(image1, 5, "bicubic", "crop");
    %      fig = uifigure;
    %      ict = ImageComparisonTool(fig, ...
    %                Position=[100 100 800 600], ...
    %                Image1=image1, ...
    %                Image2=image2, ...
    %                LinkedViewers=true);
    %       
    %   Copyright 2026 The MathWorks, Inc.

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout             matlab.ui.container.GridLayout
        ViewerContainerGrid    matlab.ui.container.GridLayout
        ControlGrid            matlab.ui.container.GridLayout
        ResetButton            matlab.ui.control.Button
        SwapButton             matlab.ui.control.Button
        LinkedViewersCheckBox  matlab.ui.control.CheckBox
        EmptyLabel2            matlab.ui.control.Label
        EmptyLabel1            matlab.ui.control.Label
    end

    properties (Dependent)
        % Image1 - Left image data for comparison
        %   Numeric array representing the left image. Accepts M-by-N (grayscale) or
        %   M-by-N-by-3 (RGB) arrays. Setting this property updates the left display.
        %   Set to [] to clear the left image.
        Image1

        % Image2 - Right image data for comparison
        %   Numeric array representing the right image. Accepts M-by-N (grayscale) or
        %   M-by-N-by-3 (RGB) arrays. Setting this property updates the right display.
        %   Set to [] to clear the right image.
        Image2

        % LinkedViewers — Synchronize zoom and pan between viewers
        %   Logical or on/off. When true (default), the left and right viewers are
        %   linked for synchronized zooming and panning.
        LinkedViewers

        % Title1 — Title text for the left viewer
        Title1

        % Title2 — Title text for the right viewer
        Title2
    end

    properties (Access = private)
        %% Viewer Components

        % Viewer1 - Left image viewer component
        Viewer1               images.ui.graphics.Viewer

        % Viewer2 - Right image viewer component
        Viewer2               images.ui.graphics.Viewer

        %% Image Display Objects - Visualization handles

        % ImageHandle1 - Display handle for first image
        ImageHandle1          images.ui.graphics.Image

        % ImageHandle2 - Display handle for second image
        ImageHandle2          images.ui.graphics.Image

        % Title1Internal - Internal storage for left viewer title
        Title1Internal = "Image 1"

        % Title2Internal - Internal storage for right viewer title
        Title2Internal = "Image 2"
    end

    properties (Access = private)
        % LinkedViewersInternal - Internal viewer synchronization state
        LinkedViewersInternal (1,1) matlab.lang.OnOffSwitchState = "on"

        % Image1Internal - Internal storage for first image data
        Image1Internal = []

        % Image2Internal - Internal storage for second image data
        Image2Internal = []
    end

    %========================= PROPERTY ACCESS METHODS ===================
    methods
        %% Property Setters and Getters

        % Image1 property
        function set.Image1(comp, value)
            % SET.Image1 — Set the left image
            % Validates and stores image data, then updates the left display

            % Validate input
            if ~isempty(value) && (~isnumeric(value) || ...
                    ~(ismatrix(value) || (ndims(value)==3 && size(value,3)==3)))
                displayError(comp, "Image1 must be a numeric array (M-by-N or M-by-N-by-3).");
                return;
            end

            % Store the image data
            comp.Image1Internal = value;

            % Update display if viewer exists
            if isvalid(comp.ImageHandle1)
                if isempty(value)
                    % Clear the display if image is empty
                    delete(comp.ImageHandle1);
                    comp.ImageHandle1 = imageshow([], Parent=comp.Viewer1);
                else
                    % Update the image display
                    delete(comp.ImageHandle1);
                    comp.ImageHandle1 = imageshow(value, Parent=comp.Viewer1);

                    % Reset view to show full image
                    reset(comp.Viewer1);
                end
            end
        end

        function value = get.Image1(comp)
            % GET.Image1 — Get the left image data

            value = comp.Image1Internal;
        end

        % Image2 property
        function set.Image2(comp, value)
            % SET.Image2 — Set the right image
            % Validates and stores image data, then updates the right display

            % Validate input
            if ~isempty(value) && (~isnumeric(value) || ...
                    ~(ismatrix(value) || (ndims(value)==3 && size(value,3)==3)))
                displayError(comp, "Image2 must be a numeric array (M-by-N or M-by-N-by-3).");
                return;
            end

            % Store the image data
            comp.Image2Internal = value;

            % Update display if viewer exists
            if isvalid(comp.ImageHandle2)
                if isempty(value)
                    % Clear the display if image is empty
                    delete(comp.ImageHandle2);
                    comp.ImageHandle2 = imageshow([], Parent=comp.Viewer2);
                else
                    % Update the image display
                    delete(comp.ImageHandle2);
                    comp.ImageHandle2 = imageshow(value, Parent=comp.Viewer2);

                    % Reset view to show full image
                    reset(comp.Viewer2);
                end
            end
        end

        function value = get.Image2(comp)
            % GET.Image2 — Get the right image data

            value = comp.Image2Internal;
        end

        function set.LinkedViewers(comp, value)
            % Set viewer synchronization state (true = linked, false = independent)
            % Actual linking is applied in update() method
            comp.LinkedViewersInternal = value;
            comp.LinkedViewersCheckBox.Value = comp.LinkedViewersInternal;
        end

        function value = get.LinkedViewers(comp)
            % Get current viewer synchronization state
            % Returns true if viewers are linked, false otherwise
            value = comp.LinkedViewersInternal;
        end

        function set.Title1(comp, value)
            comp.Title1Internal = value;
            if ~isempty(comp.Viewer1) && isvalid(comp.Viewer1)
                comp.Viewer1.Title = value;
            end
        end

        function value = get.Title1(comp)
            value = comp.Title1Internal;
        end

        function set.Title2(comp, value)
            comp.Title2Internal = value;
            if ~isempty(comp.Viewer2) && isvalid(comp.Viewer2)
                comp.Viewer2.Title = value;
            end
        end

        function value = get.Title2(comp)
            value = comp.Title2Internal;
        end
    end

    % Helper functions
    methods(Access = private)
        function displayError(comp, errorMsg)
            % DISPLAYERROR - The error message is displayed as an alert
            % dialog if the component has a parent. Otherwise the error
            % message will appear in the Command Window.

            fig = ancestor(comp, 'figure');
            if isempty(fig)
                error(errorMsg);
            else
                uialert(fig,errorMsg,"Error");
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            % Create Viewer 1 (Left side)
            % This viewer displays the first image for comparison
            comp.Viewer1 = viewer2d(comp.ViewerContainerGrid);
            comp.Viewer1.Layout.Column = 1;              % Left column
            comp.Viewer1.BackgroundColor = [0.13 0.13 0.13];  % Slightly darker gray
            comp.Viewer1.Interactions = ["zoom", "pan"]; % Enable zoom and pan interactions
            comp.Viewer1.DisplayInfo = "off";            % Hide coordinate/pixel info for cleaner look
            comp.Viewer1.Title = "Image 1";              % Label for identification

            % Create Viewer 2 (Right side)
            % This viewer displays the second image for comparison
            comp.Viewer2 = viewer2d(comp.ViewerContainerGrid);
            comp.Viewer2.Layout.Column = 2;              % Right column
            comp.Viewer2.BackgroundColor = [0.13 0.13 0.13];  % Match Viewer1 background
            comp.Viewer2.Interactions = ["zoom", "pan"]; % Enable zoom and pan interactions
            comp.Viewer2.DisplayInfo = "off";            % Hide coordinate/pixel info for cleaner look
            comp.Viewer2.Title = "Image 2";              % Label for identification

            %% ---- INITIALIZE IMAGE DISPLAY OBJECTS ----
            % Create empty image objects in each viewer
            % These will be updated when actual images are loaded
            % Starting with empty arrays prevents errors if no images are provided initially
            comp.ImageHandle1 = imageshow([], Parent=comp.Viewer1);
            comp.ImageHandle2 = imageshow([], Parent=comp.Viewer2);

            fig = ancestor(comp,"figure");
            if ~isempty(fig)
                fig.Position(3:4) = [900 850];
                comp.Units = "normalized";
                comp.Position = [0 0 1 1];
            end
        end

        % Value changed function: LinkedViewersCheckBox
        function onLinkCheckBoxChanged(comp, event)
            % ONLINKCHECKBOXCHANGED - Handle the Link Viewers checkbox state change
            
            % This callback is triggered when the user toggles the Link Viewers
            % checkbox. It synchronizes or unsynchronizes the two viewers based
            % on the checkbox state.
            
            % INPUT:
            %   event - Event data containing the new checkbox value

            % Update the internal link state
            comp.LinkedViewersInternal = event.Value;

            % Apply or remove viewer linking
            if event.Value
                % Link the viewers for synchronized zoom and pan
                linkviewers([comp.Viewer1 comp.Viewer2]);
            else
                % Unlink the viewers for independent navigation
                linkviewers([comp.Viewer1 comp.Viewer2], "off");
            end            
        end

        % Button pushed function: SwapButton
        function swapImages(comp, event)
            % SWAPIMAGES - Swap the images between the two viewers
            
            % Exchanges the images displayed in Viewer1 and Viewer2

            % Check if both images exist
            if isempty(comp.Image1Internal) || isempty(comp.Image2Internal)
                displayError(comp, "Unable to swap images. Check both images are loaded.");
                return;
            end

            % Swap the internal image references
            tempImage = comp.Image1Internal;
            comp.Image1Internal = comp.Image2Internal;
            comp.Image2Internal = tempImage;

            % Update the displays
            if isvalid(comp.ImageHandle1)
                delete(comp.ImageHandle1);
            end
            comp.ImageHandle1 = imageshow(comp.Image1Internal, Parent=comp.Viewer1);

            if isvalid(comp.ImageHandle2)
                delete(comp.ImageHandle2);
            end
            comp.ImageHandle2 = imageshow(comp.Image2Internal, Parent=comp.Viewer2);

            % Reset views to show full images
            reset(comp.Viewer1);
            reset(comp.Viewer2);

            % Re-apply viewer linking after swap
            if comp.LinkedViewersInternal
                linkviewers([comp.Viewer1 comp.Viewer2]);
            end
        end

        % Button pushed function: ResetButton
        function resetViews(comp, event)
            % RESETVIEWS - Reset both viewers to show full images
            %
            % Resets zoom and pan to display the complete images in both viewers,
            % equivalent to pressing the home button.

            % Reset both viewers to home view
            if isvalid(comp.Viewer1)
                reset(comp.Viewer1);
            end

            if isvalid(comp.Viewer2)
                reset(comp.Viewer2);
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % UPDATE — Refresh the component when properties change
            %   Synchronizes internal state with public properties and applies the
            %   current viewer linking.

            %% ---- VIEWER SYNCHRONIZATION ----
            % Update the display when properties change
            % Establish viewer linking to ensure synchronization state is current
            if comp.LinkedViewersInternal
                linkviewers([comp.Viewer1 comp.Viewer2]);
            else
                linkviewers([comp.Viewer1 comp.Viewer2], "off");
            end
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 880 812];
            comp.GridLayout = uigridlayout(comp);
            comp.ControlGrid = uigridlayout(comp.GridLayout);
            comp.EmptyLabel1 = uilabel(comp.ControlGrid);
            comp.EmptyLabel1.Text = '';
            comp.EmptyLabel2 = uilabel(comp.ControlGrid);
            comp.EmptyLabel2.Text = '';
            comp.LinkedViewersCheckBox = uicheckbox(comp.ControlGrid);
            comp.LinkedViewersCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @onLinkCheckBoxChanged, true);
            comp.LinkedViewersCheckBox.Text = 'Linked Viewers';
            comp.LinkedViewersCheckBox.Value = true;
            comp.SwapButton = uibutton(comp.ControlGrid, 'push');
            comp.SwapButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @swapImages, true);
            comp.SwapButton.Text = 'Swap Images';
            comp.ResetButton = uibutton(comp.ControlGrid, 'push');
            comp.ResetButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @resetViews, true);
            comp.ResetButton.Text = 'Reset Views';
            comp.ViewerContainerGrid = uigridlayout(comp.GridLayout);
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end